classdef VIdeo_process < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        FileMenu_2                    matlab.ui.container.Menu
        WebcamMenu                    matlab.ui.container.Menu
        WebMenu                       matlab.ui.container.Menu
        Image2                        matlab.ui.control.Image
        STOPVIDEOButton_2             matlab.ui.control.Button
        STOPCAMButton_2               matlab.ui.control.Button
        STOPButton_2                  matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        STARTButton_2                 matlab.ui.control.Button
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Vollaths_correlationLabel_2   matlab.ui.control.Label
        Variance_of_laplacianLabel_2  matlab.ui.control.Label
        Spatial_frequencyLabel_2      matlab.ui.control.Label
        Variance_of_laplacianLabel    matlab.ui.control.Label
        Vollaths_correlationLabel     matlab.ui.control.Label
        Spatial_frequencyLabel        matlab.ui.control.Label
        TenengradLabel_2              matlab.ui.control.Label
        Energy_of_laplacianLabel_2    matlab.ui.control.Label
        Modified_laplacianLabel_2     matlab.ui.control.Label
        Helmlis_mean_methodLabel_2    matlab.ui.control.Label
        Squared_gradientLabel_2       matlab.ui.control.Label
        Threshold_gradientLabel_2     matlab.ui.control.Label
        TenengradLabel                matlab.ui.control.Label
        Modified_laplacianLabel       matlab.ui.control.Label
        Energy_of_laplacianLabel      matlab.ui.control.Label
        Squared_gradientLabel         matlab.ui.control.Label
        Helmlis_mean_methodLabel      matlab.ui.control.Label
        Threshold_gradientLabel       matlab.ui.control.Label
        Energy_of_GradientLabel_2     matlab.ui.control.Label
        Energy_of_GradientLabel       matlab.ui.control.Label
        Diagonal_laplacianLabel_2     matlab.ui.control.Label
        Diagonal_laplacianLabel       matlab.ui.control.Label
        BrennerLabel2                 matlab.ui.control.Label
        BrennerLabel                  matlab.ui.control.Label
        VIDEO_CARD                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: FileMenu_2
        function FileMenu_2Selected(app, event)
             [fileName,pathName]=uigetfile('*.*');
             filePath=strcat(pathName,fileName);
             vidObj = VideoReader(filePath);
%             image 
%              vidObj.CurrentTime = 0.5;
co=1;
% co2=1;
% c=cell(1);
while hasFrame(vidObj)
    co=co+1;
    vidFrame = readFrame(vidObj);
     imshow(vidFrame,'parent',app.VIDEO_CARD);
     vidFrame=rgb2gray(vidFrame);
%      
%      fm=quality2(vidFrame);
%      app.BrennerLabel.Text=num2str(fm(1,1));
%      app.Diagonal_laplacianLabel_2.Text=num2str(fm(1,20));
%      app.Energy_of_GradientLabel_2.Text=num2str(fm(1,11));
%      app.Threshold_gradientLabel_2.Text=num2str(fm(1,12));
%      app.Squared_gradientLabel_2.Text=num2str(fm(1,13));
%      app.Helmlis_mean_methodLabel_2.Text=num2str(fm(1,14));
%      app.Energy_of_laplacianLabel_2.Text=num2str(fm(1,17));
%      app.Modified_laplacianLabel_2.Text=num2str(fm(1,18));
%      app.TenengradLabel_2.Text=num2str(fm(1,26));
%      app.Spatial_frequencyLabel_2.Text=num2str(fm(1,27));
%      app.Vollaths_correlationLabel_2.Text=num2str(fm(1,28));
%      app.Variance_of_laplacianLabel_2.Text=num2str(fm(1,29));
%      
     app.BrennerLabel.Text=num2str(Brenner(vidFrame));
     app.Diagonal_laplacianLabel_2.Text=num2str(Diagonal_laplacian(vidFrame));
     app.Energy_of_GradientLabel_2.Text=num2str(Energy_of_gradient(vidFrame));
     app.Threshold_gradientLabel_2.Text=num2str(Threshold_gradient(vidFrame));
     app.Squared_gradientLabel_2.Text=num2str(Squared_gradient(vidFrame));
     app.Helmlis_mean_methodLabel_2.Text=num2str(Helmlis_mean_method(vidFrame));
     app.Energy_of_laplacianLabel_2.Text=num2str(Energy_of_laplacian(vidFrame));
     app.Modified_laplacianLabel_2.Text=num2str( Modified_laplacian(vidFrame));
     app.TenengradLabel_2.Text=num2str(Tenengrad(vidFrame));
     app.Spatial_frequencyLabel_2.Text=num2str(Spatial_frequency(vidFrame));
     app.Vollaths_correlationLabel_2.Text=num2str(Vollaths_correlation(vidFrame));
     app.Variance_of_laplacianLabel_2.Text=num2str(Variance_of_laplacian(vidFrame));
     
end
        
    
        end

        % Menu selected function: WebcamMenu
        function WebcamMenuSelected(app, event)
            cam = webcam(1);
             cc=1;
            while cc==1
            im=cam.snapshot();
             imshow(im,'parent',app.VIDEO_CARD);
    kkey = get(gcf,'CurrentCharacter');
     disp(kkey)
        if strcmp(kkey ,' ')==1
            cc=0;
            clear cam;            
        end
            end
        
        
        end

        % Menu selected function: WebMenu
        function WebMenuSelected(app, event)
            fig = uifigure;
hlink = uihyperlink(fig);
hlink.Text = 'Video';
hlink.URL= 'https://www.youtube.com/watch?v=PCwL3-hkKrg';
%hlink.URLshow(hlink.URL,'parent',app.VIDEO_CARD);
        
        end

        % Button pushed function: STOPCAMButton_2
        function STOPCAMButton_2Pushed(app, event)

        end

        % Button pushed function: STOPVIDEOButton_2
        function STOPVIDEOButton_2Pushed(app, event)
     

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.FileMenu_2 = uimenu(app.OpenMenu);
            app.FileMenu_2.MenuSelectedFcn = createCallbackFcn(app, @FileMenu_2Selected, true);
            app.FileMenu_2.Text = 'File';
            app.WebcamMenu = uimenu(app.OpenMenu);
            app.WebcamMenu.MenuSelectedFcn = createCallbackFcn(app, @WebcamMenuSelected, true);
            app.WebcamMenu.Text = 'Webcam';
            app.WebMenu = uimenu(app.OpenMenu);
            app.WebMenu.MenuSelectedFcn = createCallbackFcn(app, @WebMenuSelected, true);
            app.WebMenu.Text = 'Web';
            app.VIDEO_CARD = uiaxes(app.UIFigure);
            xlabel(app.VIDEO_CARD, ' ')
            ylabel(app.VIDEO_CARD, ' ')
            app.VIDEO_CARD.XTick = [];
            app.VIDEO_CARD.YTick = [];
            app.BrennerLabel = uilabel(app.UIFigure);
            app.BrennerLabel.Text = 'Brenner ';
            app.BrennerLabel2 = uilabel(app.UIFigure);
            app.BrennerLabel2.Text = 'Brenner';
            app.Diagonal_laplacianLabel = uilabel(app.UIFigure);
            app.Diagonal_laplacianLabel.Text = 'Diagonal_laplacian';
            app.Diagonal_laplacianLabel_2 = uilabel(app.UIFigure);
            app.Diagonal_laplacianLabel_2.Text = 'Diagonal_laplacian';
            app.Energy_of_GradientLabel = uilabel(app.UIFigure);
            app.Energy_of_GradientLabel.Text = 'Energy_of_Gradient ';
            app.Energy_of_GradientLabel_2 = uilabel(app.UIFigure);
            app.Energy_of_GradientLabel_2.Text = 'Energy_of_Gradient ';
            app.Threshold_gradientLabel = uilabel(app.UIFigure);
            app.Threshold_gradientLabel.Text = 'Threshold_gradient';
            app.Helmlis_mean_methodLabel = uilabel(app.UIFigure);
            app.Helmlis_mean_methodLabel.Text = 'Helmlis_mean_method';
            app.Squared_gradientLabel = uilabel(app.UIFigure);
            app.Squared_gradientLabel.Text = 'Squared_gradient';
            app.Energy_of_laplacianLabel = uilabel(app.UIFigure);
            app.Energy_of_laplacianLabel.Text = 'Energy_of_laplacian';
            app.Modified_laplacianLabel = uilabel(app.UIFigure);
            app.Modified_laplacianLabel.Text = 'Modified_laplacian';
            app.TenengradLabel = uilabel(app.UIFigure);
            app.TenengradLabel.Text = 'Tenengrad';
            app.Threshold_gradientLabel_2 = uilabel(app.UIFigure);
            app.Threshold_gradientLabel_2.Text = 'Threshold_gradient';
            app.Squared_gradientLabel_2 = uilabel(app.UIFigure);
            app.Squared_gradientLabel_2.Text = 'Squared_gradient';
            app.Helmlis_mean_methodLabel_2 = uilabel(app.UIFigure);
            app.Helmlis_mean_methodLabel_2.Text = 'Helmlis_mean_method';
            app.Modified_laplacianLabel_2 = uilabel(app.UIFigure);
            app.Modified_laplacianLabel_2.Text = 'Modified_laplacian';
            app.Energy_of_laplacianLabel_2 = uilabel(app.UIFigure);
            app.Energy_of_laplacianLabel_2.Text = 'Energy_of_laplacian';
            app.TenengradLabel_2 = uilabel(app.UIFigure);
            app.TenengradLabel_2.Text = 'Tenengrad';
            app.Spatial_frequencyLabel = uilabel(app.UIFigure);
            app.Spatial_frequencyLabel.Text = 'Spatial_frequency';
            app.Vollaths_correlationLabel = uilabel(app.UIFigure);
            app.Vollaths_correlationLabel.Text = 'Vollaths_correlation';
            app.Variance_of_laplacianLabel = uilabel(app.UIFigure);
            app.Variance_of_laplacianLabel.Text = 'Variance_of_laplacian';
            app.Spatial_frequencyLabel_2 = uilabel(app.UIFigure);
            app.Spatial_frequencyLabel_2.Text = 'Spatial_frequency';
            app.Variance_of_laplacianLabel_2 = uilabel(app.UIFigure);
            app.Variance_of_laplacianLabel_2.Text = 'Variance_of_laplacian';
            app.Vollaths_correlationLabel_2 = uilabel(app.UIFigure);
            app.Vollaths_correlationLabel_2.Text = 'Vollaths_correlation';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.STARTButton_2 = uibutton(app.UIFigure, 'push');
            app.STARTButton_2.Text = 'START';
            app.Image = uiimage(app.UIFigure);
            app.STOPButton_2 = uibutton(app.UIFigure, 'push');
            app.STOPButton_2.Text = 'STOP';
            app.STOPCAMButton_2 = uibutton(app.UIFigure, 'push');
            app.STOPCAMButton_2.ButtonPushedFcn = createCallbackFcn(app, @STOPCAMButton_2Pushed, true);
            app.STOPCAMButton_2.Text = 'STOP CAM';
            app.STOPVIDEOButton_2 = uibutton(app.UIFigure, 'push');
            app.STOPVIDEOButton_2.ButtonPushedFcn = createCallbackFcn(app, @STOPVIDEOButton_2Pushed, true);
            app.STOPVIDEOButton_2.Text = 'STOP VIDEO';
            app.Image2 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIdeo_process

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end