classdef VQM_GUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FILEMenu                   matlab.ui.container.Menu
        IMPORTMenu                 matlab.ui.container.Menu
        CAPTUREVEDIOMenu           matlab.ui.container.Menu
        Image_6                    matlab.ui.control.Image
        Image_5                    matlab.ui.control.Image
        SS584RUNTIMEREMOTEVIDEOQUALITYASSESSMENTEditField  matlab.ui.control.EditField
        SS584RUNTIMEREMOTEVIDEOQUALITYASSESSMENTEditFieldLabel  matlab.ui.control.Label
        Image_4                    matlab.ui.control.Image
        RUNNINGTIMEEditFieldLabel  matlab.ui.control.Label
        RUNNINGTIMEEditField       matlab.ui.control.EditField
        VQMEditFieldLabel          matlab.ui.control.Label
        VQMEditField               matlab.ui.control.EditField
        GENERATEVQMButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: CAPTUREVEDIOMenu
        function CAPTUREVEDIOMenuSelected(app, event)
            [fileName,pathName]=uigetfile('*.*');
              filePath=strcat(pathName,fileName);
              Vptr = filePath; 
              setappdata(0,'Vptr',Vptr);
        end

        % Button pushed function: GENERATEVQMButton
        function GENERATEVQMButtonPushed(app, event)
% clc;
% Vptr =getappdata(0,'Vptr');
% %Vptr = VideoReader('C:\Users\triju\Downloads\mlab\Video003M7001.ts'); 
% Vptr1 = VideoReader(Vptr)
% % f1 = read(Vptr,[25*46 25*46+2000]);
% totalFrames = Vptr1.NumFrames;
% % f1 = read(Vptr,[25*46 25*46+2000]);
% f1 = read(VideoReader(Vptr),[25 totalFrames]);
% co=0;
% for i=1:750:totalFrames     
%         co=co+1;
%         VQM(co,1)=CQE((f1(:,:,:,i)));
% end
% app.VQMEditField.Value = num2str(VQM(1,1));
% t = table(VQM);
% writetable(t,'ss584_25045_runnumber_VideoQC.csv');
% tic
% pause(1)
% app.VQMEditField.Value = num2str(VQM(1,1));
% fileID = fopen('ss584_25045_runnumber_time','w');
% fprintf(fileID,'%4f',toc);
clc;
Vptr =getappdata(0,'Vptr');
%Vptr = VideoReader('C:\Users\triju\Downloads\mlab\Video003M7001.ts'); 
Vptr1 = VideoReader(Vptr)
% f1 = read(Vptr,[25*46 25*46+2000]);
totalFrames = Vptr1.NumFrames;
% totalFrames = floor(totalFrames/750);
% f1 = read(Vptr,[25*46 25*46+2000]);
f1 = read(VideoReader(Vptr),[25 totalFrames]);
co=0;
for i=1:750:totalFrames     
        co=co+1;
        VQM(co,1)=CQE((f1(:,:,:,i)));
end
app.VQMEditField.Value = num2str(VQM(1,1));
t = table(VQM);
writetable(t,'ss584_25045_runnumber_VideoQC.csv');
tic
        pause(1);
        fileID = fopen('ss584_25045_runnumber_time.txt','w');
        app.RUNNINGTIMEEditField.Value = num2str(toc);
        fprintf(fileID,'%.4f',toc);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FILEMenu = uimenu(app.UIFigure);
            app.FILEMenu.Text = 'FILE';
            app.IMPORTMenu = uimenu(app.FILEMenu);
            app.IMPORTMenu.Text = 'IMPORT';
            app.CAPTUREVEDIOMenu = uimenu(app.IMPORTMenu);
            app.CAPTUREVEDIOMenu.MenuSelectedFcn = createCallbackFcn(app, @CAPTUREVEDIOMenuSelected, true);
            app.CAPTUREVEDIOMenu.Text = 'CAPTUREVEDIO';
            app.GENERATEVQMButton = uibutton(app.UIFigure, 'push');
            app.GENERATEVQMButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEVQMButtonPushed, true);
            app.GENERATEVQMButton.Text = {'GENERATE VQM'; ''};
            app.VQMEditField = uieditfield(app.UIFigure, 'text');
            app.VQMEditFieldLabel = uilabel(app.UIFigure);
            app.VQMEditFieldLabel.Text = 'VQM';
            app.RUNNINGTIMEEditField = uieditfield(app.UIFigure, 'text');
            app.RUNNINGTIMEEditFieldLabel = uilabel(app.UIFigure);
            app.RUNNINGTIMEEditFieldLabel.Text = 'RUNNING TIME';
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = 'images.png';
            app.SS584RUNTIMEREMOTEVIDEOQUALITYASSESSMENTEditFieldLabel = uilabel(app.UIFigure);
            app.SS584RUNTIMEREMOTEVIDEOQUALITYASSESSMENTEditFieldLabel.Text = 'SS584 - RUNTIME REMOTE VIDEO QUALITY ASSESSMENT';
            app.SS584RUNTIMEREMOTEVIDEOQUALITYASSESSMENTEditField = uieditfield(app.UIFigure, 'text');
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageSource = 'Indian_Space_Research_Organisation_Logo.svg.png';
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageSource = 'logo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VQM_GUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end