classdef guiupdtd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        FILEMenu             matlab.ui.container.Menu
        IMPORTMenu           matlab.ui.container.Menu
        REFERENCEVEDIOMenu   matlab.ui.container.Menu
        CAPTUREVEDIOMenu     matlab.ui.container.Menu
        EditField            matlab.ui.control.EditField
        EditFieldLabel       matlab.ui.control.Label
        Button               matlab.ui.control.Button
        ENDEditField         matlab.ui.control.NumericEditField
        ENDEditFieldLabel    matlab.ui.control.Label
        STARTEditField       matlab.ui.control.NumericEditField
        STARTEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: REFERENCEVEDIOMenu
        function REFERENCEVEDIOMenuSelected(app, event)
            [fileName,pathName]=uigetfile('*.*');
              filePath=strcat(pathName,fileName);
              filena1=fileName;
              setappdata(0,'filena1',filena1);
              vptr = filePath;
              setappdata(0,'vptr',vptr);
        end

        % Menu selected function: CAPTUREVEDIOMenu
        function CAPTUREVEDIOMenuSelected(app, event)
             [fileName,pathName]=uigetfile('*.*');
              filePath=strcat(pathName,fileName);
              filena2=fileName;
              setappdata(0,'filena2',filena2);
              vptr1 = filePath; 
              setappdata(0,'vptr1',vptr1);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
vptr =getappdata(0,'vptr');
filena1 = getappdata(0,'filena1');
vptr1 =getappdata(0,'vptr1');
filena2 = getappdata(0,'filena2');
%[fileName,pathName]=uigetfile('*.*');
%filePath=strcat(pathName,fileName);
%vptr = filePath;
%Vptr1 = VideoReader('C:\Users\triju\Downloads\mlab\Video001M1001.ts');
RefVideo=filena1;
captureVideo=filena2;
    f1 = read(VideoReader(vptr),[25*46 25*46+2000]);
   %f1 = read(Vptr,[25*46 25*46+2000]);
   f2 = read(VideoReader(vptr1),[100 2117]);
    co=0;
    startFrame=25*46;
    endFrame=25*46+2000;
    for i=1:25:2002 
        co=co+1;
        [mssim, ssim_map] = ssim(f1(:,:,:,i),f2(:,:,:,i+16));
        SSIM(co,1)=mssim;    
    end
    SSIM=mean(SSIM);
    app.EditField.Value = num2str(SSIM(1,1));
    t = table(RefVideo,captureVideo,startFrame,endFrame,SSIM(1,1));
    writetable(t,'M.csv');
    figure,imshow(f2(:,:,:,17));
    figure,imshow(f1(:,:,:,1));
    SSIM=mean(SSIM);
%             a = app.STARTEditField.Value;
%             b = app.ENDEditField.Value;
%             c = a + b;
%             app.EditField.Value = num2str(c);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FILEMenu = uimenu(app.UIFigure);
            app.FILEMenu.Text = 'FILE';
            app.IMPORTMenu = uimenu(app.FILEMenu);
            app.IMPORTMenu.Text = 'IMPORT';
            app.REFERENCEVEDIOMenu = uimenu(app.IMPORTMenu);
            app.REFERENCEVEDIOMenu.MenuSelectedFcn = createCallbackFcn(app, @REFERENCEVEDIOMenuSelected, true);
            app.REFERENCEVEDIOMenu.Text = 'REFERENCE VEDIO';
            app.CAPTUREVEDIOMenu = uimenu(app.IMPORTMenu);
            app.CAPTUREVEDIOMenu.MenuSelectedFcn = createCallbackFcn(app, @CAPTUREVEDIOMenuSelected, true);
            app.CAPTUREVEDIOMenu.Text = 'CAPTUREVEDIO';
            app.STARTEditFieldLabel = uilabel(app.UIFigure);
            app.STARTEditFieldLabel.Text = 'START';
            app.STARTEditField = uieditfield(app.UIFigure, 'numeric');
            app.ENDEditFieldLabel = uilabel(app.UIFigure);
            app.ENDEditFieldLabel.Text = 'END';
            app.ENDEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiupdtd

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end