classdef guiupdtd_Sample2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FILEMenu                   matlab.ui.container.Menu
        IMPORTMenu                 matlab.ui.container.Menu
        REFERENCEVEDIOMenu         matlab.ui.container.Menu
        CAPTUREVEDIOMenu           matlab.ui.container.Menu
        Image                      matlab.ui.control.Image
        RUNNINGTIMEEditField       matlab.ui.control.EditField
        RUNNINGTIMEEditFieldLabel  matlab.ui.control.Label
        SSIMVALUEEditField         matlab.ui.control.EditField
        SSIMVALUEEditFieldLabel    matlab.ui.control.Label
        SSIMGENERATEButton         matlab.ui.control.Button
        ENDEditField               matlab.ui.control.NumericEditField
        ENDEditFieldLabel          matlab.ui.control.Label
        STARTEditField             matlab.ui.control.NumericEditField
        STARTEditFieldLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: REFERENCEVEDIOMenu
        function REFERENCEVEDIOMenuSelected(app, event)
            [fileName,pathName]=uigetfile('*.*');
              filePath=strcat(pathName,fileName);
              filena1=fileName;
              setappdata(0,'filena1',filena1);
              vptr = filePath;
              setappdata(0,'vptr',vptr);
        end

        % Menu selected function: CAPTUREVEDIOMenu
        function CAPTUREVEDIOMenuSelected(app, event)
             [fileName,pathName]=uigetfile('*.*');
              filePath=strcat(pathName,fileName);
              filena2=fileName;
              setappdata(0,'filena2',filena2);
              vptr1 = filePath; 
              setappdata(0,'vptr1',vptr1);
        end

        % Button pushed function: SSIMGENERATEButton
        function SSIMGENERATEButtonPushed(app, event)
clc;
vptr =getappdata(0,'vptr');
filena1 = getappdata(0,'filena1');
vptr1 =getappdata(0,'vptr1');
filena2 = getappdata(0,'filena2');
startFrame=app.STARTEditField.Value;
endFrame=app.ENDEditField.Value;
%[fileName,pathName]=uigetfile('*.*');
%filePath=strcat(pathName,fileName);
%vptr = filePath;
%Vptr1 = VideoReader('C:\Users\triju\Downloads\mlab\Video001M1001.ts');
% Nrows = Vptr.height;
% Ncols = Vptr.width;
    f1 = read(VideoReader(vptr),[25*46 25*46+2000]);
   %f1 = read(Vptr,[25*46 25*46+2000]);
   f2 = read(VideoReader(vptr1),[100 2117]);
    co=0;
    for i=1:750:2000 
        co=co+1;
        [mssim, ssim_map] = ssim(f1(:,:,:,i),f2(:,:,:,i+16));
        SSIM(co,1)=mssim;  
        RefVideo(co,1)="Video001.mp4";
        captureVideo(co,1)="Video001M1001.ts";
    end
%     startFrame=25*46;
%     endFrame=25*46+2000;
%     SSIM=mean(SSIM);
    app.SSIMVALUEEditField.Value = num2str(SSIM(1,1));
%RefVideo=filena1;
% captureVideo=filena2;
t = table(RefVideo,captureVideo,SSIM);
writematrix(t,"ss584_25045_runnumber_VideoQC(1).csv",'WriteMode','append');
figure,imshow(f2(:,:,:,17));
figure,imshow(f1(:,:,:,1));
    tic
        pause(1);
    app.RUNNINGTIMEEditField.Value = num2str(toc);    
% Vptr = VideoReader('E:\SS584_Video_Quality\SS584-Video-Quality\Ref-videos\Video001.mp4');
% Vptr1 = VideoReader('E:\SS584_Video_Quality\SS584-Video-Quality\M1-Videos\Video001M1001.ts');
% Nrows = Vptr.height;
% Ncols = Vptr.width;
% f1 = read(Vptr,[25*46 25*46+2000]);
% f2 = read(Vptr1,[100 2117]);
% co=0;
% for i=1:750:2000
%         co=co+1;
%         mae1=sum(sum(sum(abs(im2double(f1(:,:,:,i))-im2double(f2(:,:,:,i+16)))*255)))/(Nrows*Ncols*3);
%         [mssim, ssim_map] = ssim(f1(:,:,:,i),f2(:,:,:,i+16));  
%         MAE(co,1)=mae1;
%         SSIM(co,1)=mssim;
% end
% app.SSIMVALUEEditField.Value = num2str(SSIM(1,1));
% RefVideo="Video001.mp4";
% captureVideo="Video001M1001.ts";
% startFrame=25*45.4;
% endFrame=25*45.4+2000;
% t = table(MAE,SSIM);
% writetable(t,'ss584_25045_runnumber_VideoQC.csv');
% figure,imshow(f2(:,:,:,17));
% figure,imshow(f1(:,:,:,1));
%     tic
%         pause(1);
%         fileID = fopen('ss584_25045_runnumber_time.txt','w');
%         app.RUNNINGTIMEEditField.Value = num2str(toc);
%         fprintf(fileID,'%.4f',toc);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FILEMenu = uimenu(app.UIFigure);
            app.FILEMenu.Text = 'FILE';
            app.IMPORTMenu = uimenu(app.FILEMenu);
            app.IMPORTMenu.Text = 'IMPORT';
            app.REFERENCEVEDIOMenu = uimenu(app.IMPORTMenu);
            app.REFERENCEVEDIOMenu.MenuSelectedFcn = createCallbackFcn(app, @REFERENCEVEDIOMenuSelected, true);
            app.REFERENCEVEDIOMenu.Text = 'REFERENCE VEDIO';
            app.CAPTUREVEDIOMenu = uimenu(app.IMPORTMenu);
            app.CAPTUREVEDIOMenu.MenuSelectedFcn = createCallbackFcn(app, @CAPTUREVEDIOMenuSelected, true);
            app.CAPTUREVEDIOMenu.Text = 'CAPTUREVEDIO';
            app.STARTEditFieldLabel = uilabel(app.UIFigure);
            app.STARTEditFieldLabel.Text = 'START';
            app.STARTEditField = uieditfield(app.UIFigure, 'numeric');
            app.ENDEditFieldLabel = uilabel(app.UIFigure);
            app.ENDEditFieldLabel.Text = 'END';
            app.ENDEditField = uieditfield(app.UIFigure, 'numeric');
            app.SSIMGENERATEButton = uibutton(app.UIFigure, 'push');
            app.SSIMGENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @SSIMGENERATEButtonPushed, true);
            app.SSIMGENERATEButton.Text = 'SSIM GENERATE';
            app.SSIMVALUEEditFieldLabel = uilabel(app.UIFigure);
            app.SSIMVALUEEditFieldLabel.Text = 'SSIM  VALUE';
            app.SSIMVALUEEditField = uieditfield(app.UIFigure, 'text');
            app.RUNNINGTIMEEditFieldLabel = uilabel(app.UIFigure);
            app.RUNNINGTIMEEditFieldLabel.Text = 'RUNNING TIME';
            app.RUNNINGTIMEEditField = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'GUI LOGO.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiupdtd_Sample2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end